classdef EstimateV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea              matlab.ui.control.TextArea
        CardiacParametersfortheevaluationofCardiacFunctionLabel  matlab.ui.control.Label
        WavesButtonGroup      matlab.ui.container.ButtonGroup
        AllButton             matlab.ui.control.RadioButton
        LeftVentricleButton   matlab.ui.control.RadioButton
        RightVentricleButton  matlab.ui.control.RadioButton
        LVMButton             matlab.ui.control.RadioButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SegApp % Description
        w1
        w2
        w3
        w4
        w5
        w6
        w7
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, seg, val, x, y, y1, y2, f)
            app.SegApp=seg;
            app.w1=val;
            app.w2=x;
            app.w3=y;
            app.w4=y1;
            app.w5=f;
            app.w6=y2;
            app.w7=f(4);
            %%%%% CÁLCULO PARÁMETROS Funcion Cardiaca
            EDV=str2double(val(1));
            ESV=str2double(val(3));
            EDRV=str2double(val(2));
            ESRV=str2double(val(4));
            StV=EDV-ESV;
            RVStv=EDRV-ESRV;
            EF=(StV/EDV)*100;
            EFRV=(RVStv/EDRV)*100;
            CO=(StV*f(4))/1000;
            
            StV=num2str(StV,'%.2f');
            EF=num2str(EF,'%.2f');
            RVStv=num2str(RVStv,'%.2f');
            EFRV=num2str(EFRV,'%.2f');
            CO=num2str(CO,'%.2f');
            HR=num2str(f(4),'%.2f');
            app.TextArea.Value= sprintf(['Left Ventricle\n- EDV LV: %s ml\n- ESV LV: %s ml' ...
                '\n- SV: %s ml\n- EF: %s %%\n- CO: %s L/min\n- HR: %s bpm\nRight Ventricle\n- ' ...
                'EDV RV: %s ml\n- ESV RV: %s ml\n- RVSV: %s ml\n- ' ...
                'EF: %s %%'],val(1),val(3),StV,EF,CO,HR,val(2),val(4),RVStv,EFRV);
            
            xlim(app.UIAxes,[0-f(1)*0.1,(f(1)+(f(1)*0.1))])
            if EDV>EDRV
                lim=EDV;
            else
                lim=EDRV;
            end
            ylim(app.UIAxes,[0,(lim+(lim*0.1))])
            plot(app.UIAxes,x,y); 
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,y1);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,y2);
            hold(app.UIAxes,'off')
            xt = [f(2) f(3)];
            yt = [EDV ESV];
            str = {'ED','ES'};
            text(app.UIAxes,xt,yt,str);
            lgd = legend(app.UIAxes,'Left','Right','LVM','Location','southeast');
            title(lgd,'Ventricle')
        end

        % Selection changed function: WavesButtonGroup
        function WavesButtonGroupSelectionChanged(app, event)
            if app.AllButton.Value==true

                EDV=str2double(app.w1(1));
                EDRV=str2double(app.w1(2));
                ESV=str2double(app.w1(3));
                xlim(app.UIAxes,[0-app.w5(1)*0.1,(app.w5(1)+(app.w5(1)*0.1))])
                if EDV>EDRV
                    lim=EDV;
                else
                    lim=EDRV;
                end
                ylim(app.UIAxes,[0,(lim+(lim*0.1))])
                plot(app.UIAxes,app.w2,app.w3); 
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,app.w2,app.w4);
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.w2,app.w6);
                hold(app.UIAxes,'off')
                hold(app.UIAxes, 'off')
                xt = [app.w5(2) app.w5(3)];
                yt = [EDV ESV];
                str = {'ED','ES'};
                text(app.UIAxes,xt,yt,str);
                lgd = legend(app.UIAxes,'Left','Right','LVM','Location','southeast');
                title(lgd,'Ventricle')
                
            end
            if app.LeftVentricleButton.Value==true

                EDV=str2double(app.w1(1));
                EDRV=str2double(app.w1(2));
                ESV=str2double(app.w1(3));
                xlim(app.UIAxes,[0-app.w5(1)*0.1,(app.w5(1)+(app.w5(1)*0.1))])
                if EDV>EDRV
                    lim=EDV;
                else
                    lim=EDRV;
                end
                ylim(app.UIAxes,[0,(lim+(lim*0.1))])
                plot(app.UIAxes,app.w2,app.w3); 
                xt = [app.w5(2) app.w5(3)];
                yt = [EDV ESV];
                str = {'ED','ES'};
                text(app.UIAxes,xt,yt,str);
                lgd = legend(app.UIAxes,'Left','Location','southeast');
                title(lgd,'Ventricle')
                
            end
            if app.RightVentricleButton.Value==true

                EDV=str2double(app.w1(1));
                EDRV=str2double(app.w1(2));
                ESV=str2double(app.w1(3));
                xlim(app.UIAxes,[0-app.w5(1)*0.1,(app.w5(1)+(app.w5(1)*0.1))])
                if EDV>EDRV
                    lim=EDV;
                else
                    lim=EDRV;
                end
                ylim(app.UIAxes,[0,(lim+(lim*0.1))])
                plot(app.UIAxes,app.w2,app.w4); 
                lgd = legend(app.UIAxes,'Right','Location','southeast');
                title(lgd,'Ventricle')
            end
            if app.LVMButton.Value==true

                EDV=str2double(app.w1(1));
                EDRV=str2double(app.w1(2));                
                ESV=str2double(app.w1(3));
                xlim(app.UIAxes,[0-app.w5(1)*0.1,(app.w5(1)+(app.w5(1)*0.1))])
                if EDV>EDRV
                    lim=EDV;
                else
                    lim=EDRV;
                end
                ylim(app.UIAxes,[0,(lim+(lim*0.1))])
                plot(app.UIAxes,app.w2,app.w6); 
                lgd = legend(app.UIAxes,'LVM','Location','southeast');
                title(lgd,'Myo')
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.CardiacParametersfortheevaluationofCardiacFunctionLabel = uilabel(app.UIFigure);
            app.CardiacParametersfortheevaluationofCardiacFunctionLabel.Text = 'Cardiac Parameters for the evaluation of Cardiac Function';
            app.WavesButtonGroup = uibuttongroup(app.UIFigure);
            app.WavesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WavesButtonGroupSelectionChanged, true);
            app.WavesButtonGroup.Title = 'Waves';
            app.AllButton = uiradiobutton(app.WavesButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.LeftVentricleButton = uiradiobutton(app.WavesButtonGroup);
            app.LeftVentricleButton.Text = 'Left Ventricle';
            app.RightVentricleButton = uiradiobutton(app.WavesButtonGroup);
            app.RightVentricleButton.Text = {'Right Ventricle'; ''};
            app.LVMButton = uiradiobutton(app.WavesButtonGroup);
            app.LVMButton.Text = {'LVM'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time(ms)')
            ylabel(app.UIAxes, 'Volume (ml)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EstimateV(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end