classdef Segmentacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OPENMenu                      matlab.ui.container.Menu
        DicomFileMenu                 matlab.ui.container.Menu
        matFileMenu                   matlab.ui.container.Menu
        PreviousFileMenu              matlab.ui.container.Menu
        SAVEMenu                      matlab.ui.container.Menu
        SegmentationMenu              matlab.ui.container.Menu
        EstimateValuesMenu            matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        SLICESliderLabel              matlab.ui.control.Label
        SLICESlider                   matlab.ui.control.Slider
        VENTSEGLabel                  matlab.ui.control.Label
        DropDownLabel                 matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        INFORMATIONEditFieldLabel     matlab.ui.control.Label
        INFORMATIONEditField          matlab.ui.control.EditField
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        ESTIMATEButton                matlab.ui.control.Button
        CORRECTButton                 matlab.ui.control.Button
        CardiacCyclePhaseButtonGroup  matlab.ui.container.ButtonGroup
        EndDiastoleButton             matlab.ui.control.RadioButton
        EndSistoleButton              matlab.ui.control.RadioButton
        FreeHandButtonGroup           matlab.ui.container.ButtonGroup
        AddButton                     matlab.ui.control.RadioButton
        DeleteButton                  matlab.ui.control.RadioButton
        dButton                       matlab.ui.control.RadioButton
        SAVEButton                    matlab.ui.control.Button
        PhaseViewButton               matlab.ui.control.Button
        ProcessButtonGroup            matlab.ui.container.ButtonGroup
        LoadFoldersButton             matlab.ui.control.RadioButton
        LoadDicomFilesButton          matlab.ui.control.RadioButton
        SegmentingMiddleSliceButton   matlab.ui.control.RadioButton
        SelectingEDandESButton        matlab.ui.control.RadioButton
        SegmentingEDButton            matlab.ui.control.RadioButton
        SegmentingESButton            matlab.ui.control.RadioButton
        PHASESpinnerLabel             matlab.ui.control.Label
        PHASESpinner                  matlab.ui.control.Spinner
        SegmentationButtonGroup       matlab.ui.container.ButtonGroup
        ActualButton                  matlab.ui.control.RadioButton
        AutoCorrectButton             matlab.ui.control.RadioButton
        NoneButton                    matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        EDdata % Matriz Slices ED
        ESdata % Matriz Slices ES
        original1 % Matriz RMC Original
        ED % Valor Phase ED
        ES % Valor Phase ES
        slvalue % Valor Slider Slice
        phase % Valor Phase ED-ES #
        im % Matriz Slices Actual 
        totPh % Matriz de acuerdo a la Phase
        Cy % Seleccion Segment Total o ED-ES
        roi=images.roi.Freehand;
        EDaut %Correct Automatica ED
        ESaut %Correct Automatica ES
        folder_name % Seleccion de carpeta
        numberOfFiles_dcm %Para validar dicom files
        EstApp %Estimate App
        imR %Slice Medio
        PixS %Informacion Dicom Pixel Spacing
        sBs %Informacion Dicom Spacing btw slice
        prev %Vector para imagen previa de segmentacion
        newim %mascara roi image freehand
        x % data in x (time) 
        y % data in y (Volume) LV
        y1 %data in y (Volume) RV
        y2 %data in y (Volume) Myo
        HR %Heart Rate
        Tx1 %ID patient
        info_dicom %Informacion *cabecera Dicom
        info_dicom1 %AUX
    end
    
    methods (Access = private)
%%%%%%%%%%%%%%%%%%%%%%%%% FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function func(app,x,value)
            
            im0=app.original1;
            [~, ~, c, d]=size(im0);
            disableDefaultInteractivity(app.UIAxes);
            
            if x==1  %ALL
                aux1=(app.im==1);
                aux2=(app.im==2);
                aux3=(app.im==3);
                axes(app.UIAxes);
                green = double(cat(3, zeros(size(im0(:,:,round(c/2),round(d/2)))), ones(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2))))));
                alpha = double(aux3(:,:,value))*0.25;
                imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes);
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes, 'on')
                imagesc(green,'parent', app.UIAxes,'AlphaData', alpha);
                red = double(cat(3, ones(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2))))));
                alpha = double(aux1(:,:,value))*0.25;
                imagesc(red,'parent', app.UIAxes,'AlphaData', alpha);
                blue = cat(3, zeros(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2)))), ones(size(im0(:,:,round(c/2),round(d/2)))));
                alpha = double(aux2(:,:,value))*0.25;
                imagesc(blue,'parent', app.UIAxes,'AlphaData', alpha);
                hold(app.UIAxes, 'off')
            elseif x==2  %LV
                aux1=(app.im==1);
                axes(app.UIAxes);
                red = double(cat(3, ones(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2))))));
                alpha = double(aux1(:,:,value))*0.25;
                imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes); 
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes, 'on')
                imagesc(red,'parent', app.UIAxes,'AlphaData', alpha);
                hold(app.UIAxes, 'off')
            elseif x==3 %RV
                aux3=(app.im==3);
                axes(app.UIAxes);
                green = double(cat(3, zeros(size(im0(:,:,round(c/2),round(d/2)))), ones(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2))))));
                alpha = double(aux3(:,:,value))*0.25;
                imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes); 
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes, 'on')
                imagesc(green,'parent', app.UIAxes,'AlphaData', alpha);
                hold(app.UIAxes, 'off')              
            elseif x==4 %MYO
                aux2=(app.im==2);
                axes(app.UIAxes);
                blue = cat(3, zeros(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2)))), ones(size(im0(:,:,round(c/2),round(d/2)))));
                alpha = double(aux2(:,:,value))*0.25;
                imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes);
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes, 'on')
                imagesc(blue,'parent', app.UIAxes,'AlphaData', alpha);
                hold(app.UIAxes, 'off')
            elseif x==5 %ALL Slices
                aux1=(app.im==1);
                aux2=(app.im==2);
                aux3=(app.im==3);
                axes(app.UIAxes);
                green = double(cat(4, zeros(size(im0(:,:,:,round(d/2)))), ones(size(im0(:,:,:,round(d/2)))), zeros(size(im0(:,:,:,round(d/2))))));
                green = permute(green,[1 2 4 3]);
                out=imtile(im0(:,:,:,app.phase));
                imagesc(out,'Parent',app.UIAxes);
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes, 'on')
                out1=imtile(green);
                a=double(aux3)*0.25;
                outA=imtile(a);
                imagesc(out1,'parent', app.UIAxes,'AlphaData', outA);
                hold(app.UIAxes, 'off')
                red = double(cat(4, ones(size(im0(:,:,:,round(d/2)))), zeros(size(im0(:,:,:,round(d/2)))), zeros(size(im0(:,:,:,round(d/2))))));
                red = permute(red,[1 2 4 3]);
                hold(app.UIAxes, 'on')
                out1=imtile(red);
                a=double(aux1)*0.25;
                outA=imtile(a);
                imagesc(out1,'parent', app.UIAxes,'AlphaData', outA);
                hold(app.UIAxes, 'off')
                blue = double(cat(4, zeros(size(im0(:,:,:,round(d/2)))), zeros(size(im0(:,:,:,round(d/2)))), ones(size(im0(:,:,:,round(d/2))))));
                blue = permute(blue,[1 2 4 3]);
                hold(app.UIAxes, 'on')
                out1=imtile(blue);
                a=double(aux2)*0.25;
                outA=imtile(a);
                imagesc(out1,'parent', app.UIAxes,'AlphaData', outA);
                hold(app.UIAxes, 'off')
            elseif x==6  %No mostrar segmentación Preview None Button
                imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes);
                colormap(app.UIAxes,'gray')
            elseif x==7  %LV RV MYO Prev
                if app.DropDown.Value==2
                    aux1=(app.prev==1);
                    axes(app.UIAxes);
                    red = double(cat(3, ones(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2))))));
                    alpha = double(aux1(:,:,value))*0.25;
                    imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes); 
                    colormap(app.UIAxes,'gray')
                    hold(app.UIAxes, 'on')
                    imagesc(red,'parent', app.UIAxes,'AlphaData', alpha);
                    hold(app.UIAxes, 'off')                    
                elseif app.DropDown.Value==3
                    aux3=(app.prev==3);
                    axes(app.UIAxes);
                    green = double(cat(3, zeros(size(im0(:,:,round(c/2),round(d/2)))), ones(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2))))));
                    alpha = double(aux3(:,:,value))*0.25;
                    imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes); 
                    colormap(app.UIAxes,'gray')
                    hold(app.UIAxes, 'on')
                    imagesc(green,'parent', app.UIAxes,'AlphaData', alpha);
                    hold(app.UIAxes, 'off')
                elseif app.DropDown.Value==4
                    aux2=(app.prev==2);
                    axes(app.UIAxes);
                    blue = cat(3, zeros(size(im0(:,:,round(c/2),round(d/2)))), zeros(size(im0(:,:,round(c/2),round(d/2)))), ones(size(im0(:,:,round(c/2),round(d/2)))));
                    alpha = double(aux2(:,:,value))*0.25;
                    imagesc(im0(:,:,value,app.phase),'Parent',app.UIAxes);
                    colormap(app.UIAxes,'gray')
                    hold(app.UIAxes, 'on')
                    imagesc(blue,'parent', app.UIAxes,'AlphaData', alpha);
                    hold(app.UIAxes, 'off')
                end
            end           
        end
        
        function item(app,z)
            if z==1
                app.SelectingEDandESButton.Text='Preprocessing and Selecting ED and ES';
                app.SegmentingEDButton.Text='Segmenting and Preprocessing ED';
                app.SegmentingESButton.Text='Segmenting and Preprocessing ES';
                app.LoadFoldersButton.Text='Load Folders';
                app.LoadDicomFilesButton.Text = 'Load Files...';
                app.LoadFoldersButton.Value=false;
                app.LoadDicomFilesButton.Value=true;
                app.LoadFoldersButton.FontWeight = 'normal';
                app.LoadDicomFilesButton.FontWeight = 'bold';
            elseif z==2
                app.LoadDicomFilesButton.Text='Load Files';
                app.SegmentingMiddleSliceButton.Text='Segmenting Middle Slice...';
                app.LoadDicomFilesButton.Value=false;
                app.SegmentingMiddleSliceButton.Value=true;
                app.LoadDicomFilesButton.FontWeight = 'normal';
                app.SegmentingMiddleSliceButton.FontWeight = 'bold';
            elseif z==3
                app.SegmentingMiddleSliceButton.Text='Segmenting Middle Slice';
                app.SelectingEDandESButton.Text='Preprocessing and Selecting ED and ES...';
                app.SegmentingMiddleSliceButton.Value=false;
                app.SelectingEDandESButton.Value=true;
                app.SelectingEDandESButton.FontWeight = 'bold';
                app.SegmentingMiddleSliceButton.FontWeight = 'normal';
            elseif z==4
                app.SegmentingEDButton.Text='Segmenting and Preprocessing ED...';
                app.SelectingEDandESButton.Text='Preprocessing and Selecting ED and ES';
                app.SegmentingEDButton.Value=true;
                app.SelectingEDandESButton.Value=false;
                app.SelectingEDandESButton.FontWeight = 'normal';
                app.SegmentingEDButton.FontWeight = 'bold';
            elseif z==5
                app.SegmentingEDButton.Text='Segmenting and Preprocessing ED';
                app.SegmentingESButton.Text='Segmenting and Preprocessing ES...';
                app.SegmentingEDButton.Value=false;
                app.SegmentingESButton.Value=true;
                app.SegmentingEDButton.FontWeight = 'normal';
                app.SegmentingESButton.FontWeight = 'bold';
            elseif z==6
                app.SegmentingMiddleSliceButton.Visible= 'on';
                app.SelectingEDandESButton.Visible= 'on';
                app.SelectingEDandESButton.Text='Preprocessing Segmentations';
                app.LoadDicomFilesButton.Text='Load Files';
                app.SegmentingMiddleSliceButton.Text='Segmenting All Slices...';
                app.LoadDicomFilesButton.Value=false;
                app.SegmentingMiddleSliceButton.Value=true;
                app.LoadDicomFilesButton.FontWeight = 'normal';
                app.SegmentingMiddleSliceButton.FontWeight = 'bold';
            elseif z==7             
                app.SelectingEDandESButton.Text='Preprocessing Segmentations';
                app.SegmentingMiddleSliceButton.Text='Segmenting All Slices';
                app.SegmentingMiddleSliceButton.Value=false;
                app.SelectingEDandESButton.Value=true;
                app.SegmentingMiddleSliceButton.FontWeight = 'normal';
                app.SelectingEDandESButton.FontWeight = 'bold';
            end          
        end
        %FUNCION INICIAL 
        function init(app)
            
            app.folder_name = uigetdir([],'Load Folder...');

            %Validación
            if app.folder_name==0
                return;
            end
            app.LoadFoldersButton.Text='Load Folders...';
            app.VENTSEGLabel.Visible = 'on';
            app.SegmentingMiddleSliceButton.Visible= 'off';
            app.SelectingEDandESButton.Visible= 'off';
            app.SegmentingEDButton.Visible= 'off';
            app.SegmentingESButton.Visible= 'off';
            app.ProcessButtonGroup.Visible = 'on';
            pause(1);

            %Almacenamos número de carpetas y crea lista para identificar archivos dicom
            allSubFolders = genpath(app.folder_name);
            remain = allSubFolders;
            %Guarda las carpetas de acuerdo al Sistema Operativo
            if ismac || isunix
                listOfFolderNames = {};
                while true
                    [singleSubFolder, remain] = strtok(remain, ':');
                    if isempty(singleSubFolder)
                        break;
                    end
                    listOfFolderNames = [listOfFolderNames singleSubFolder];
                end
                numberOfFolders = length(listOfFolderNames);
            elseif ispc
                listOfFolderNames = {};
                while true
                    [singleSubFolder, remain] = strtok(remain, ';');
                    if isempty(singleSubFolder)
                        break;
                    end
                    listOfFolderNames = [listOfFolderNames singleSubFolder];
                end
                numberOfFolders = length(listOfFolderNames);
            end
            %Variables a utilizar Nombre de archivos dicom y número
            files_names_dcm = [];
            cont_dcm = 1;
            item(app,1);
            pause(1);
            %Lectura de todas las carpetas para encontrar archivos .dcm
            for k = 1 : numberOfFolders
                thisFolder = listOfFolderNames{k};
                filePattern_dcm = sprintf('%s/*.dcm', thisFolder);
                baseFileNames_dcm = dir(filePattern_dcm);
                app.numberOfFiles_dcm = length(baseFileNames_dcm);
                if app.numberOfFiles_dcm>=1
                    for n = 1:app.numberOfFiles_dcm
                        files_names_dcm{cont_dcm} = [thisFolder,filesep,baseFileNames_dcm(n).name];
                        cont_dcm = cont_dcm + 1;
                    end
                end
            end
            %Verificar archivos ocultos
            dcm_temp = [];
            cont=1;
            for ff = 1:length(files_names_dcm)
                if isempty(strfind(files_names_dcm{ff},'._'))==1
                    dcm_temp{cont}=files_names_dcm{ff};
                    cont = cont + 1;
                end
            end
            files_names_dcm = dcm_temp;
            %Validación de archivos .dcm
            if app.numberOfFiles_dcm==0
                %MOSTRAR ALGUN MENSAJE NO HAY DICOM
                app.VENTSEGLabel.Visible = 'off';
                app.ProcessButtonGroup.Visible = 'off';            
                return;
            end
            app.info_dicom1 = dicominfo(files_names_dcm{1});
            % Sigle File
            if length(files_names_dcm)==1
                if app.info_dicom1.SeriesDescription=="M2D cine VOLUMEN"
                    im1 = dicomread(files_names_dcm{1});
                    IM = zeros(app.info_dicom1.Rows,app.info_dicom1.Columns, app.info_dicom1.Private_2001_1018,app.info_dicom1.Private_2001_1017);
                    info=app.info_dicom1;
                    for n = 1:app.info_dicom1.NumberOfFrames
                        fc = eval(['info.PerFrameFunctionalGroupsSequence.Item_',num2str(n),'.Private_2005_140f.Item_1.Private_2001_1008']); % fase cardiaca
                        slc = eval(['info.PerFrameFunctionalGroupsSequence.Item_',num2str(n),'.Private_2005_140f.Item_1.Private_2001_100a']); % slice
                        IM(:,:,slc,fc) = im1(:,:,1,n);
                    end
                    try
                        idicom(app)
                    catch
                        %%%%%%AGREGAR PANTALLA PARA INGRESAR VALORES
                    end
                else
                    %NO HAY DICOM M2D CINE VOLUMEN  Solo Philips Validado
                    app.VENTSEGLabel.Visible = 'off';
                    app.ProcessButtonGroup.Visible = 'off';
                    app.numberOfFiles_dcm=0;
                    return
                end
            % Volume Serie File  FIND M2D cine VOLUMEN
            else
                BitRange = 20000;
                for k=1:length(files_names_dcm)
                    %Utilizamos códigos para tomar solo los datos
                    %necesarios (No se puede leer con dicominfo x tiempo)
                    am = fopen(char(files_names_dcm(k)),'r');
                    c2 = fread(am,BitRange,'uint8=>uint8');
                    fclose(am);
                    try
                        DAs = strfind(c2',[8 0 62 16 76 79]);
                        DAs2 = double(c2(DAs+6));
                        SerieDescrip = char(c2((DAs+8):(DAs+7+DAs2)));
                        SerieDescrip = strcat(SerieDescrip');
                    catch
                        %AGREGAR MENSAJE NO DICOM
                        SerieDescrip = 'Unspecified';
                    end
                
                    if SerieDescrip=="M2D cine VOLUMEN"
                        im1 = dicomread(files_names_dcm{k});
                        app.info_dicom1 = dicominfo(files_names_dcm{k});
                        try
                            IM = zeros(app.info_dicom1.Rows, app.info_dicom1.Columns, app.info_dicom1.Private_2001_1018, app.info_dicom1.Private_2001_1017);
                            info=app.info_dicom1;
                            for n = 1:app.info_dicom1.NumberOfFrames
                                fc = eval(['info.PerFrameFunctionalGroupsSequence.Item_',num2str(n),'.Private_2005_140f.Item_1.Private_2001_1008']); % fase cardiaca
                                slc = eval(['info.PerFrameFunctionalGroupsSequence.Item_',num2str(n),'.Private_2005_140f.Item_1.Private_2001_100a']); % slice
                                IM(:,:,slc,fc) = im1(:,:,1,n);
                            end
                        catch
                        end
                        try
                            idicom(app)
                        catch
                        end
                    end 
                end
            end
            %Enviamos el volumen MRI a segmentar
            try
                app.original1=IM;
                original=IM;
                save original.mat original 
            catch
                app.VENTSEGLabel.Visible = 'off';
                app.ProcessButtonGroup.Visible = 'off';
                app.numberOfFiles_dcm=0;                
            end
            
        end
        
        function idicom(app)
            try
                %CALCULO Pixel Spacing
                app.PixS=app.info_dicom1.PerFrameFunctionalGroupsSequence.Item_1.Private_2005_140f.Item_1.PixelSpacing(1);
                %CALCULO Spacing Btw Slices
                app.sBs=app.info_dicom1.PerFrameFunctionalGroupsSequence.Item_1.Private_2005_140f.Item_1.SliceThickness;
                %CALCULO Heart RATE
                app.HR=app.info_dicom1.PerFrameFunctionalGroupsSequence.Item_1.Private_2005_140f.Item_1.HeartRate;
                app.info_dicom=app.info_dicom1;
            catch
                try
                    %CALCULO Pixel Spacing
                    app.PixS=app.info_dicom1.Item_1.PixelSpacing(1);
                    %CALCULO Spacing Btw Slices
                    app.sBs=app.info_dicom1.Item_1.SliceThickness;
                    %CALCULO Heart RATE
                    app.HR=app.info_dicom1.Item_1.HeartRate;
                    app.info_dicom=app.info_dicom1;
                catch
                    try
                        %CALCULO Pixel Spacing
                        app.PixS=app.info_dicom1.Item_1.PixelSpacing(1);
                        %CALCULO Spacing Btw Slices
                        app.sBs=app.info_dicom1.Item_1.SliceThickness;
                        %CALCULO Heart RATE
                        time0=app.info_dicom.Item_1.TriggerTime;
                        time1=app.info_dicom.Item_2.TriggerTime;
                        [~,~,~,f]=size(app.original1);
                        app.HR=(60000/(time1-time0))/(f-1);
                        app.info_dicom=app.info_dicom1; 
                    catch
                        try
                            %CALCULO Pixel Spacing
                            app.PixS=app.info_dicom1.PixelSpacing(1);
                            %CALCULO Spacing Btw Slices
                            app.sBs=app.info_dicom1.SliceThickness;
                            %CALCULO Heart RATE
                            app.HR=60;
                            app.info_dicom=app.info_dicom1;
                        catch
                            app.PixS=0.5;
                            app.sBs=10;
                            app.HR=60;
                            app.info_dicom=app.info_dicom1;
                        end
                    end
                end    
            end

        end
        %%%%%%%%%%%%%%%%%% TEXT BOX INFORMATION %%%%%%%%%%%%%%%%%%%%%%%%
        
        function textInf(app)
            try 
                app.Tx1= app.info_dicom1.PatientID;
            catch
                try 
                    app.Tx1= app.info_dicom1.Item_1.PatientID;
                catch 
                    app.Tx1='Unknown';
                end
            end
            try 
                Tx2= app.info_dicom1.PatientBirthDate ;
            catch
                try
                    Tx2= app.info_dicom1.Item_1.PatientBirthDate ;
                catch 
                    Tx2='Unknown';
                end
            end
            try 
                Tx3= num2str(app.info_dicom1.PatientWeight) ;
            catch
                try
                    Tx3= num2str(app.info_dicom1.Item_1.PatientWeight) ;
                catch 
                    Tx3='Unknown';
                end
            end
            try 
                Tx4= app.info_dicom1.StudyDescription ;
            catch
                try
                    Tx4= app.info_dicom1.Item_1.StudyDescription ;
                catch
                    Tx4='Unknown';
                end
            end
            try 
                Tx5= app.info_dicom1.StudyDate ;
            catch
                try
                    Tx5= app.info_dicom1.Item_1.StudyDate ;
                catch
                    Tx5='Unknown';
                end
            end
            try 
                Tx6= app.info_dicom1.Manufacturer;
            catch
                try
                    Tx6= app.info_dicom1.Item_1.Manufacturer;
                catch
                    Tx6='Unknown'; 
                end
            end
            try 
                Tx7= app.info_dicom1.InstitutionName ;
            catch
                try
                    Tx7= app.info_dicom1.Item_1.InstitutionName ;                    
                catch
                    Tx7='Unknown';                    
                end                
            end
            [~,~,~,d]=size(app.original1);
            tS=num2str(d);
            Ed1=num2str(app.ED);
            Es1=num2str(app.ES);
            app.TextArea.Value= sprintf(['- Patient ID: %s\n- Birth Date: %s' ...
                '\n- Weight: %skg\n- Study Description: %s\n- Study Date: %s\n- ' ...
                'Manufacturer: %s\n- Institution Name: %s\n- Total Slices: %s' ...
                '\n- End Diastole: %s' ...
                '\n- End Sistole: %s'],app.Tx1,Tx2,Tx3,Tx4,Tx5,Tx6,Tx7,tS,Ed1,Es1);
        end
        
        function Dato=filtro(app,data)
            aux1=(data==1);
            aux2=(data==2);
            aux3=(data==3);
    
            for i=1:size(aux1,3)
                %Filtrar 1 solo objeto
                aux1(:,:,i)= bwareafilt(aux1(:,:,i),1);
                %Rellenar espacios
                aux1(:,:,i)=imfill(aux1(:,:,i),'holes');
                %Filtrar 1 solo objeto
                aux2(:,:,i)= bwareafilt(aux2(:,:,i),1);
                %Filtrar 1 solo objeto
                aux3(:,:,i)= bwareafilt(aux3(:,:,i),1);
                %Rellenar espacios
                aux3(:,:,i)= imfill(aux3(:,:,i),'holes');
            end
                
            [L1,n1] = bwlabeln(aux1,6);
            Var1=zeros(1,n1);
            for i=1:n1
                Var1(i)=sum(L1(:)==i);
            end
            [~,I1]=max(Var1);
            aux1=L1==I1;
            
            [L2,n2] = bwlabeln(aux2,6);
            Var2=zeros(1,n2);
            for i=1:n2
                Var2(i)=sum(L2(:)==i);
            end
            [~,I2]=max(Var2);
            aux2=L2==I2;
            
            [L3,n3] = bwlabeln(aux3,6);
            Var3=zeros(1,n3);
            for i=1:n3
                Var3(i)=sum(L3(:)==i);
            end
            [~,I3]=max(Var3);
            aux3=L3==I3;
            
            Edata=(1*aux1)+(2*aux2)+(3*aux3);
            Dato=Edata;
        end
        
        function showinform(app,init)
            %GRAFICAR INICIAL
            app.im=init;
            app.slvalue=1;
            app.EndDiastoleButton.Value = true;
            app.PHASESpinner.Value=1;
            %CALL function to text box information
            textInf(app)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %APP Slider Inicial
            app.SLICESlider.Visible = 'on';
            app.SLICESliderLabel.Visible = 'on';
            app.SLICESlider.Value = 1;
            [~,~,c]=size(init);
            app.SLICESlider.Limits(1) = 1;
            app.SLICESlider.Limits(2) = c;
            x = 1:1:c;
            app.SLICESlider.MinorTicks = x;
            app.SLICESlider.MajorTicks = x;
            %GRAFICO
            app.ProcessButtonGroup.Visible = 'off';
            montage(app.original1(:,:,:,app.phase),'DisplayRange',[],'Parent',app.UIAxes);
            func(app,1,1);
            
            %INFORMACION 
            app.INFORMATIONEditFieldLabel.Visible = 'on';
            app.INFORMATIONEditField.Visible = 'on';
            app.TextArea.Visible = 'on';
            app.ESTIMATEButton.Visible = 'on';
            app.CORRECTButton.Visible = 'on';
            app.PhaseViewButton.Visible = 'on';
            
            %DROPDOWN
            app.DropDownLabel.Visible = 'on';
            app.DropDown.Visible = 'on';
            %SAVE MENU
            app.SegmentationMenu.Enable='on';
            app.EstimateValuesMenu.Enable='on';
        end
        
        function data=matCal(app,value) %Func Adicional a val/Estimate
            [~,~,c]=size(value);
            mask=value;
            aux1=(mask==1); 
            aux2=(mask==2); 
            aux3=(mask==3);
            A1=sum(sum(aux1));
            A2=sum(sum(aux2));
            A3=sum(sum(aux3));
            med=round(c/2)+1;            
            data=[sum(A1),sum(A2),sum(A3),A1(med),A3(med),A2(med)];
        end
        
        function val=estimateV(app) %Estimar la funcion cardiaca
            
            if app.Cy==1
                %CALCULO DE AREAS x slice
                data=matCal(app,app.ESdata);
                SLV=data(1);SMyo=data(2);SRV=data(3);
                data=matCal(app,app.EDdata);
                DLV=data(1);DMyo=data(2);DRV=data(3);
                %CALCULO DE VOLUMEN
                VDLV=(DLV*app.sBs*app.PixS)/1000;
                disp(app.sBs);
                disp(app.PixS);
                EDV=VDLV;
                VDLV=num2str(VDLV,'%.2f');
                
                VDRV=(DRV*app.sBs*app.PixS)/1000;
                EDRV=VDRV;
                VDRV=num2str(VDRV,'%.2f');
                
                DMyo=(DMyo*app.sBs*app.PixS)/1000;
                MyoD=DMyo;
                DMyo=num2str(DMyo,'%.2f');
                
                VSLV=(SLV*app.sBs*app.PixS)/1000;
                ESV=VSLV;
                VSLV=num2str(VSLV,'%.2f');
                
                VSRV=(SRV*app.sBs*app.PixS)/1000;
                ESRV=VSRV;
                VSRV=num2str(VSRV,'%.2f');
                
                SMyo=(SMyo*app.sBs*app.PixS)/1000;
                MyoS=SMyo;
                SMyo=num2str(SMyo,'%.2f');
                
                [~,~,c]=size(app.EDdata);
                [~,~,f]=size(app.imR);
                
                maskSm=app.imR;
                aux1M=(maskSm==1);
                aux2M=(maskSm==2);
                aux3M=(maskSm==3);
                A1M = sum(sum(aux1M));
                A2M = sum(sum(aux2M));
                A3M = sum(sum(aux3M));
                app.y=zeros(1,f);
                app.y1=zeros(1,f);
                app.y2=zeros(1,f);
                for k=1:f
                    app.y(k)=(A1M(k)/data(4))*EDV;
                    app.y1(k)=(A3M(k)/data(5))*EDRV;
                    app.y2(k)=(A2M(k)/data(6))*MyoD;
                    %app.y(k)=(A1M(k)/A1S(med))*ESV; Otra opcion Mod orden en data
                end
                %ESCALAMOS valores Y en LV
                cero=app.y-min(app.y);
                escalada=cero/max(cero);
                salida=escalada*(EDV-ESV) + ESV;
                app.y=salida;
                %ESCALAMOS valores Y en RV
                cero1=app.y1-min(app.y1);
                escalada1=cero1/max(cero1);
                salida1=escalada1*(EDRV-ESRV) + ESRV;
                app.y1=salida1;
                %Vector X tiempo
                r1=round((60/app.HR)*1000);
                app.x=linspace(0,r1,f);
            end
            
            if app.Cy==2
                %CALCULO DE AREAS x slice
                data=matCal(app,app.totPh(:,:,:,app.ES));
                SLV=data(1);SMyo=data(2);SRV=data(3);
                data=matCal(app,app.totPh(:,:,:,app.ED));
                DLV=data(1);DMyo=data(2);DRV=data(3);
                
                %CALCULO DE VOLUMEN
                VDLV=(DLV*app.sBs*app.PixS)/1000;
                EDV=VDLV;
                VDLV=num2str(VDLV,'%.2f');
                
                VDRV=(DRV*app.sBs*app.PixS)/1000;
                EDRV=VDRV;
                VDRV=num2str(VDRV,'%.2f');
                
                DMyo=(DMyo*app.sBs*app.PixS)/1000;
                MyoD=DMyo;
                DMyo=num2str(DMyo,'%.2f');
                
                VSLV=(SLV*app.sBs*app.PixS)/1000;
                ESV=VSLV;
                VSLV=num2str(VSLV,'%.2f');
                
                VSRV=(SRV*app.sBs*app.PixS)/1000;
                ESRV=VSRV;
                VSRV=num2str(VSRV,'%.2f');
                
                SMyo=(SMyo*app.sBs*app.PixS)/1000;
                MyoS=SMyo;
                SMyo=num2str(SMyo,'%.2f');
                
                [~,~,~,f]=size(app.totPh);

                app.y=zeros(1,f);
                app.y1=zeros(1,f);
                app.y2=zeros(1,f);
                %Volume Calculation All slices
                for k=1:f
                    maskSm=app.totPh(:,:,:,k);
                    aux1M=(maskSm==1);
                    aux2M=(maskSm==2);
                    aux3M=(maskSm==3);
                    A1M = sum(sum(aux1M));
                    A2M = sum(sum(aux2M));
                    A3M = sum(sum(aux3M));
                    
                    app.y(k)=(sum(A1M)*app.sBs*app.PixS)/1000;
                    app.y1(k)=(sum(A3M)*app.sBs*app.PixS)/1000;
                    app.y2(k)=(sum(A2M)*app.sBs*app.PixS)/1000;
                end
                %Vector X tiempo
                r1=round((60/app.HR)*1000);
                app.x=linspace(0,r1,f);
                
            end
            val=convertCharsToStrings({VDLV,VDRV,VSLV,VSRV,DMyo,SMyo});
        end
        
        function SegmentFunc(app)
            
            %SELECT ALL OR ED/ES SEGMENTATION
            answer=questdlg('Do you want a segmentation of all phases or only ED and ES cycles?','Select','All Phases','ED and ES','ED and ES');

            %%%%%%%ORIGINAL%%%VOLVER A CARGAR ARCHIVO
            if answer == ""
                msgbox('No option selected')
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return
            end
            switch answer
                case 'ED and ES'
                    app.Cy=1;
                    %Segmenting Middle Slice to get ED and ES
                    app.SegmentingMiddleSliceButton.Visible= 'on';
                    app.SelectingEDandESButton.Visible= 'on';
                    app.SegmentingEDButton.Visible= 'on';
                    app.SegmentingESButton.Visible= 'on';
                    item(app,2);
                    pause(1);
                    py.maintesis.url('original.mat',2,0);
                    ruta_c = mfilename('fullpath');
                    [ruta,~,~] = fileparts(ruta_c);
                    %OGet the result and Morfological Operations
                    file='/middle.mat';
                    app.imR=load([ruta,file]);
                    app.imR=cell2mat(struct2cell(app.imR));
                    %Morfological Operations
                    app.imR=filtro(app,app.imR);
                    item(app,3);
                    pause(1);
                    %FIND ED AND ES ---- AREA
                    resultP=app.imR;
                    [~,~,c]=size(resultP);
                    mask=resultP;
                    auxT=(mask==1);
                    val = zeros(1,c);
                    for k=1:c
                        val(k)=sum(sum(auxT(:,:,k)));
                        %stats = regionprops(auxT(:,:,k),'area');
                        %val(k)=stats.Area;
                    end
                    app.ED=find(val==max(val));
                    app.ED=app.ED(1);
                    app.ES=find(val==min(val));
                    app.ES=app.ES(1);
                    delete('middle.mat');
                    item(app,4);
                    pause(1);
                    % SEGMENT ED
                    py.maintesis.url('original.mat',3,app.ED);
                    file='/phase.mat';
                    app.EDdata=load([ruta,file]);
                    app.EDdata=cell2mat(struct2cell(app.EDdata));
                    % AUTO CORRECTION
                    try
                        py.vae_postprocessing.direct('phase.mat',1);
                        file='/auto.mat';
                        app.EDaut=load([ruta,file]);
                        app.EDaut=cell2mat(struct2cell(app.EDaut));
                    catch
                        app.EDaut=zeros(size(app.EDdata));
                        
                    end
                    
                    % Morfologil Operations
                    app.EDdata=filtro(app,app.EDdata);
                    item(app,5);
                    pause(1);
                    %Segment ES
                    py.maintesis.url('original.mat',3,app.ES);
                    file='/phase.mat';
                    app.ESdata=load([ruta,file]);
                    app.ESdata=cell2mat(struct2cell(app.ESdata));
                    %Auto Correction
                    try
                        py.vae_postprocessing.direct('phase.mat',1);
                        file='/auto.mat';
                        app.ESaut=load([ruta,file]);
                        app.ESaut=cell2mat(struct2cell(app.ESaut));
                        delete('auto.mat');
                    catch
                        app.ESaut=zeros(size(app.ESdata));
                    end
                    
                    %
                    %Morfological Operation
                    app.ESdata=filtro(app,app.ESdata);   
                    
                    delete('original.mat');
                    delete('phase.mat');
                    app.phase=app.ED;
                    %APP Box Inicial
                    app.CardiacCyclePhaseButtonGroup.Visible = 'on';                 
                    showinform(app,app.EDdata);
                
                case 'All Phases' 
                    app.Cy=2;
                    item(app,6);
                    pause(1);
                    py.maintesis.url('original.mat',1,0);
                    ruta_c = mfilename('fullpath');
                    [ruta,~,~] = fileparts(ruta_c);
                    %Obtenemos el resultado y realizamos O.Morfologicas
                    file='/resultado.mat';
                    imRes=load([ruta,file]);
                    delete('resultado.mat');
                    imRes=cell2mat(struct2cell(imRes));
                    app.totPh=imRes;
                    %OPERACIONES MORFOLOGICAS
                    item(app,7);
                    pause(1);
                    [~,~,c,d]=size(app.totPh);
                    delete('original.mat');
                    for k = 1 : d
                        app.totPh(:,:,:,k)=filtro(app,imRes(:,:,:,k));
                    end

                    %FIND ED AND ES ---- AREA
                    med=round(c/2)+1;
                    resultP=app.totPh(:,:,med,:);
                    app.imR=app.totPh(:,:,med,:);
                    [~,~,c]=size(resultP);
                    mask=resultP;
                    auxT=(mask==1);
                    val = zeros(1,c);
                    for k=1:c
                        val(k)=sum(sum(auxT(:,:,k)));
                        %stats = regionprops(auxT(:,:,k),'area');
                        %val(k)=stats.Area;
                    end
                    app.ED=find(val==max(val));
                    app.ED=app.ED(1);
                    app.ES=find(val==min(val));
                    app.ES=app.ES(1);
                    app.phase=1;
                    app.PHASESpinnerLabel.Visible = 'on';
                    app.PHASESpinner.Visible = 'on';
                    app.AutoCorrectButton.Visible='off';
                    
                    app.PHASESpinner.Limits = [1 d];
                    showinform(app,app.totPh(:,:,:,1));                 
            end
            
        end
        
        function CleanS(app)
            app.CardiacCyclePhaseButtonGroup.Visible = 'off';
            app.ProcessButtonGroup.Visible = 'off';
            app.PHASESpinnerLabel.Visible = 'off';
            app.PHASESpinner.Visible = 'off';
            app.SLICESlider.Visible = 'off';
            app.SLICESliderLabel.Visible = 'off';
            app.INFORMATIONEditFieldLabel.Visible = 'off';
            app.INFORMATIONEditField.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.ESTIMATEButton.Visible = 'off';
            app.CORRECTButton.Visible = 'off';
            app.PhaseViewButton.Visible = 'off';
            app.FreeHandButtonGroup.Visible='off';
            app.SAVEButton.Visible='off';
            app.SegmentationButtonGroup.Visible='off';
            app.EstimateValuesMenu.Enable = 'off';
            app.SegmentationMenu.Enable='off';
            app.SegmentingESButton.FontWeight = 'normal';       
            
            try 
                delete(app.UIAxes);
                app.UIAxes = uiaxes(app.GridLayout);
                app.UIAxes.Toolbar.Visible = 'off';
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.Visible = 'off';
                app.UIAxes.Layout.Row = [3 5];
                app.UIAxes.Layout.Column = [2 3];
            catch  
            end
            
            app.VENTSEGLabel.Visible = 'off';
            
            %DROPDOWN
            app.DropDownLabel.Visible = 'off';
            app.DropDown.Visible = 'off';
        end
        
    end
 %%%%%%%%%%%%%%%%%%%%%%%%%END OF FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Menu selected function: DicomFileMenu
        function DicomFileMenuSelected(app, event)
            CleanS(app)            
            app.ProcessButtonGroup.Visible = 'on';
            app.VENTSEGLabel.Visible = 'on';
            app.LoadDicomFilesButton.Text='Load Files';
            app.LoadFoldersButton.FontWeight = 'bold';
            app.LoadDicomFilesButton.FontWeight = 'normal';
            app.LoadFoldersButton.Value=true;
            app.SegmentingMiddleSliceButton.Visible= 'off';
            app.SelectingEDandESButton.Visible= 'off';
            app.SegmentingEDButton.Visible= 'off';
            app.SegmentingESButton.Visible= 'off';
            app.SelectingEDandESButton.FontWeight = 'normal';
            
            init(app)
            %VALIDATE FOLDER
            
            if app.folder_name==0
                msgbox('No Folders found')
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;
            end
            %VALIDATE DICOM FILES
            if app.numberOfFiles_dcm==0
                msgbox('No dicom files found');
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;
            end
            %Execute function to segmentation
            SegmentFunc(app);
                                        
        end

        % Value changed function: SLICESlider
        function SLICESliderValueChanged(app, event)
            %delete(app.roi);
            app.slvalue=app.SLICESlider.Value;
            app.slvalue= round(app.slvalue);
            app.SLICESlider.Value = app.slvalue;
            func(app,app.DropDown.Value,app.slvalue)
            %POR SI ESTA EN CORRECT PARA ACTUALIZAR
            if app.DropDown.Value~=1       
                app.SAVEButton.Visible = 'off';
                app.FreeHandButtonGroup.Visible = 'off';
                app.SegmentationButtonGroup.Visible='off';
                app.ESTIMATEButton.Visible = 'on';
            end
        end

        % Value changing function: SLICESlider
        function SLICESliderValueChanging(app, event)
            %changingValue = event.Value;
            %value= round(changingValue);
            %func(app,app.CardiacCyclePhaseListBox.Value,value)
        end

        % Selection changed function: CardiacCyclePhaseButtonGroup
        function CardiacCyclePhaseButtonGroupSelectionChanged(app, event)
            %delete(app.roi);
            if app.EndDiastoleButton.Value
                app.phase=app.ED;
                app.im=app.EDdata;
                func(app,app.DropDown.Value,app.slvalue)
            else
                app.phase=app.ES;
                app.im=app.ESdata;
                func(app,app.DropDown.Value,app.slvalue)
            end
            
            if app.DropDown.Value~=1       
                app.SAVEButton.Visible = 'off';
                app.FreeHandButtonGroup.Visible = 'off';
                app.SegmentationButtonGroup.Visible='off';
                app.ESTIMATEButton.Visible = 'on';
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value==1
                app.CORRECTButton.Enable = 'off';
                app.ESTIMATEButton.Enable = 'on';
                app.ESTIMATEButton.Visible = 'on';
                app.FreeHandButtonGroup.Visible = 'off';
                app.SegmentationButtonGroup.Visible='off';
                app.SAVEButton.Visible = 'off';
                func(app,1,app.slvalue)
            elseif value==2
                app.CORRECTButton.Enable = 'on';
                app.ESTIMATEButton.Enable = 'off';
                func(app,2,app.slvalue)                
            elseif value==3
                app.CORRECTButton.Enable = 'on';
                app.ESTIMATEButton.Enable = 'off';
                func(app,3,app.slvalue)
            elseif value==4
                app.CORRECTButton.Enable = 'on';
                app.ESTIMATEButton.Enable = 'off';
                func(app,4,app.slvalue)
            end
            
            if app.DropDown.Value~=1       
                app.SAVEButton.Visible = 'off';
                app.FreeHandButtonGroup.Visible = 'off';
                app.SegmentationButtonGroup.Visible='off';  
                app.ESTIMATEButton.Visible = 'on';
            end
        end

        % Button pushed function: CORRECTButton
        function CORRECTButtonPushed(app, event)
            %Create Temp Imagen
            func(app,app.DropDown.Value,app.slvalue);
            app.dButton.Value=true;
            app.FreeHandButtonGroup.Visible = 'on';
            app.SAVEButton.Visible = 'on';
            app.ESTIMATEButton.Visible = 'off';
            app.SegmentationButtonGroup.Visible='on';
            app.ActualButton.Value=true;
            %app.roi=images.roi.Freehand;       
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.ESTIMATEButton.Visible = 'on';
            app.SAVEButton.Visible = 'off';
            app.FreeHandButtonGroup.Visible = 'off';
            app.SegmentationButtonGroup.Visible='off';
            aux1=(app.im==1);
            aux2=(app.im==2);
            aux3=(app.im==3);
            if isvalid(app.roi)==0
                app.roi=images.roi.Freehand;
            end
            %ELIMINAR DEFINITIVAMENTE SEGMENTACION INDIVIDUAL
            if app.NoneButton.Value
                if app.DropDown.Value==2
                    aux1(:,:,app.slvalue)=0;
                elseif app.DropDown.Value==3
                    aux3(:,:,app.slvalue)=0;
                elseif app.DropDown.Value==4
                    aux2(:,:,app.slvalue)=0;
                end
                %CORRECCION INDIVIDUAL
                if app.Cy==1
                    if app.EndDiastoleButton.Value
                        app.EDdata=aux1+2*aux2+3*aux3;
                        app.im=app.EDdata;
                    else
                        app.ESdata=aux1+2*aux2+3*aux3;
                        app.im=app.ESdata;
                    end   
                else 
                    app.totPh(:,:,:,app.PHASESpinner.Value)=aux1+2*aux2+3*aux3;
                    app.im=app.totPh(:,:,:,app.PHASESpinner.Value);
                end      
            elseif app.ActualButton.Value
                %Añadir o quitar freehand
                if app.DropDown.Value==2
                    
                    if app.AddButton.Value==true
                        aux1(:,:,app.slvalue)=double(aux1(:,:,app.slvalue)+app.newim);
                        aux1(:,:,app.slvalue)=(aux1(:,:,app.slvalue)>=1)==1;
                    else
                        aux1(:,:,app.slvalue)=double((aux1(:,:,app.slvalue)-app.newim)>0);
                          
                    end
                elseif app.DropDown.Value==3
                   
                    if app.AddButton.Value==true
                        aux3(:,:,app.slvalue)=double(aux3(:,:,app.slvalue)+app.newim);
                        aux3(:,:,app.slvalue)=(aux3(:,:,app.slvalue)>=1)==1;
                    else
                        aux3(:,:,app.slvalue)=double((aux3(:,:,app.slvalue)-app.newim)>0);
                        
                    end
                elseif app.DropDown.Value==4
                 
                    if app.AddButton.Value==true
                        aux2(:,:,app.slvalue)=double(aux2(:,:,app.slvalue)+app.newim);
                        aux2(:,:,app.slvalue)=(aux2(:,:,app.slvalue)>=1)==1;
                    else
                        aux2(:,:,app.slvalue)=double((aux2(:,:,app.slvalue)-app.newim)>0);
                        
                    end
                end
                if app.Cy==1
                    if app.EndDiastoleButton.Value
                        app.EDdata=aux1+2*aux2;
                        aux1=app.EDdata==1;
                        aux2=app.EDdata==2;
                        aux4=app.EDdata==3;
                        app.EDdata=aux1+2*aux2+3*aux3+aux4;
                        aux1=app.EDdata==1;
                        aux2=app.EDdata==2;
                        aux3=app.EDdata==3;
                        aux4=app.EDdata==4;
                        aux5=app.EDdata==5;
                        app.EDdata=aux1+2*aux2+3*aux3+aux4+3*aux5;
                        app.im=app.EDdata;
                    else
                        app.ESdata=aux1+2*aux2;
                        aux1=app.ESdata==1;
                        aux2=app.ESdata==2;
                        aux4=app.ESdata==3;
                        app.ESdata=aux1+2*aux2+3*aux3+aux4;
                        aux1=app.ESdata==1;
                        aux2=app.ESdata==2;
                        aux3=app.ESdata==3;
                        aux4=app.ESdata==4;
                        aux5=app.ESdata==5;
                        app.ESdata=aux1+2*aux2+3*aux3+aux4+3*aux5;
                        app.im=app.ESdata;
                    end   
                else
                    app.totPh(:,:,:,app.PHASESpinner.Value)=aux1+2*aux2;
                    aux1=app.totPh(:,:,:,app.PHASESpinner.Value)==1;
                    aux2=app.totPh(:,:,:,app.PHASESpinner.Value)==2;
                    aux4=app.totPh(:,:,:,app.PHASESpinner.Value)==3;
                    app.totPh(:,:,:,app.PHASESpinner.Value)=aux1+2*aux2+3*aux3+aux4;
                    aux1=app.totPh(:,:,:,app.PHASESpinner.Value)==1;
                    aux2=app.totPh(:,:,:,app.PHASESpinner.Value)==2;
                    aux3=app.totPh(:,:,:,app.PHASESpinner.Value)==3;
                    aux4=app.totPh(:,:,:,app.PHASESpinner.Value)==4;
                    aux5=app.totPh(:,:,:,app.PHASESpinner.Value)==5;
                    app.totPh(:,:,:,app.PHASESpinner.Value)=aux1+2*aux2+3*aux3+aux4+3*aux5;
                    app.im=app.totPh(:,:,:,app.PHASESpinner.Value);
                end   
            elseif app.AutoCorrectButton.Value
                %PROGRAMAR%%%%%%%%%%%%
            end
            %delete(app.roi);
            %app.roi=images.roi.Freehand;
            func(app,app.DropDown.Value,app.slvalue);
        end

        % Button pushed function: PhaseViewButton
        function PhaseViewButtonPushed(app, event)
            func(app,5,1);
            disp(app.HR);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            [~, ~, c,~]=size(app.original1);
            if strcmp(key,'leftarrow')
                app.slvalue = app.slvalue-1; % left value
                if app.slvalue==0
                    app.slvalue=1;
                end
            elseif strcmp(key,'rightarrow')
                app.slvalue = app.slvalue+1; % right value
                if app.slvalue==(c+1)
                    app.slvalue=c;
                end
            end
            app.SLICESlider.Value = app.slvalue; % set the slider value
	        SLICESliderValueChanged(app, event)
            
            if app.DropDown.Value~=1       
                app.SAVEButton.Visible = 'off';
                app.FreeHandButtonGroup.Visible = 'off';
                app.SegmentationButtonGroup.Visible='off';   
            end
            

        end

        % Callback function
        function SegmentationMenu_2Selected(app, event)
        %NONE YET    
        end

        % Value changed function: PHASESpinner
        function PHASESpinnerValueChanged(app, event)
            %delete(app.roi);
            value = app.PHASESpinner.Value;
            app.im=app.totPh(:,:,:,value);
            app.phase=value;
            func(app,app.DropDown.Value,app.slvalue);
            
            if app.DropDown.Value~=1       
                app.SAVEButton.Visible = 'off';
                app.FreeHandButtonGroup.Visible = 'off';
                app.SegmentationButtonGroup.Visible='off';
                app.ESTIMATEButton.Visible = 'on';
            end

        end

        % Selection changed function: SegmentationButtonGroup
        function SegmentationButtonGroupSelectionChanged(app, event)
            %delete(app.roi);
            %app.roi=images.roi.Freehand;
            
            if app.NoneButton.Value
                func(app,6,app.slvalue); 
            end
            if app.ActualButton.Value
                
                func(app,app.DropDown.Value,app.slvalue)
                
            end
            if app.Cy==1
                if app.AutoCorrectButton.Value
                    if app.EndDiastoleButton.Value
                        app.im=app.EDaut;
                        func(app,app.DropDown.Value,app.slvalue)
                        app.im=app.EDdata;
                    else
                        app.im=app.ESaut;  
                        func(app,app.DropDown.Value,app.slvalue)
                        app.im=app.ESdata;
                    end
                end
            end
            
        end

        % Selection changed function: ProcessButtonGroup
        function ProcessButtonGroupSelectionChanged(app, event)
        %NONE
        end

        % Selection changed function: FreeHandButtonGroup
        function FreeHandButtonGroupSelectionChanged(app, event)
            delete(app.roi);
            func(app,app.DropDown.Value,app.slvalue);
            a=msgbox('If you are not going to use the add or delete tool, press the escape key before taking any action');
            pause(2)
            delete(a);
            app.roi=images.roi.Freehand;
            value = app.DropDown.Value;
            app.prev=app.im;
            if app.AddButton.Value==true %ADD PREVIEW
                if value==2
                    app.roi = drawfreehand('Color','r','parent', app.UIAxes);
                    aux1=(app.prev==1);
                    app.newim=createMask(app.roi,aux1(:,:,app.slvalue));
                    aux1(:,:,app.slvalue)=double(aux1(:,:,app.slvalue)+app.newim);
                    aux1(:,:,app.slvalue)=(aux1(:,:,app.slvalue)>=1)==1;
                    app.prev=aux1;                   
                    
                end
                if value==3
                    app.roi = drawfreehand('Color','g','parent', app.UIAxes);
                    aux3=(app.prev==3);
                    app.newim=createMask(app.roi,aux3(:,:,app.slvalue));
                    aux3(:,:,app.slvalue)=double(aux3(:,:,app.slvalue)+app.newim);
                    aux3(:,:,app.slvalue)=(aux3(:,:,app.slvalue)>=1)==1;
                    app.prev=3*aux3;
                    
                    
                end
                if value==4
                    app.roi = drawfreehand('Color','b','parent', app.UIAxes);
                    aux2=(app.prev==2);
                    app.newim=createMask(app.roi,aux2(:,:,app.slvalue));
                    aux2(:,:,app.slvalue)=double(aux2(:,:,app.slvalue)+app.newim);
                    aux2(:,:,app.slvalue)=(aux2(:,:,app.slvalue)>=1)==1;
                    app.prev=2*aux2;
                end
            else %DELETE PREVIEW 
                app.roi = drawfreehand('Color','k','parent', app.UIAxes);
                if value==2
                    
                    aux1=(app.prev==1);
                    app.newim=createMask(app.roi,aux1(:,:,app.slvalue));
                    aux1(:,:,app.slvalue)=double((aux1(:,:,app.slvalue)-app.newim)>0);
                    app.prev=aux1;                   
                    
                end
                if value==3
                    
                    aux3=(app.prev==3);
                    app.newim=createMask(app.roi,aux3(:,:,app.slvalue));
                    aux3(:,:,app.slvalue)=double((aux3(:,:,app.slvalue)-app.newim)>0);
                    app.prev=3*aux3;
                    
                    
                end
                if value==4
                    
                    aux2=(app.prev==2);
                    app.newim=createMask(app.roi,aux2(:,:,app.slvalue));
                    aux2(:,:,app.slvalue)=double((aux2(:,:,app.slvalue)-app.newim)>0);
                    app.prev=2*aux2;
                end
                
            end
            func(app,7,app.slvalue);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app.roi)
            delete(app)
        end

        % Button pushed function: ESTIMATEButton
        function ESTIMATEButtonPushed(app, event)
            val=estimateV(app);
            if app.Cy==1
                [~,~,f]=size(app.imR);
                r1=round((60/app.HR)*1000);
                r2=(app.ED-1)*(r1/(f-1));
                r3=(app.ES-1)*(r1/(f-1));
                r=[r1,r2,r3,app.HR];
                app.EstApp = EstimateV(app,val,app.x,app.y,app.y1,app.y2,r);
            end
            if app.Cy==2
                [~,~,f]=size(app.imR);
                r1=round((60/app.HR)*1000);
                r2=(app.ED-1)*(r1/(f-1));
                r3=(app.ES-1)*(r1/(f-1));
                r=[r1,r2,r3,app.HR];
                app.EstApp = EstimateV(app,val,app.x,app.y,app.y1,app.y2,r); 
            end 
        end

        % Menu selected function: SegmentationMenu
        function SegmentationMenuSelected(app, event)
            nameT = erase(app.Tx1,'.');
            nameT = erase(nameT,':');
            saveR=['Save_' nameT];
            mkdir(saveR);
            cd(saveR);            
            original=app.original1;
            info_dicom=app.info_dicom;
            segment=zeros(size(app.original1));
            if app.Cy==1
                segment(:,:,:,app.ED)=app.EDdata;
                segment(:,:,:,app.ES)=app.ESdata;
                save original.mat original
                save segment.mat segment
                save info_dicom.mat info_dicom
                cd ..
            end
            if app.Cy==2
                segment=app.totPh;
                save original.mat original
                save segment.mat segment
                save info_dicom.mat info_dicom
                cd ..
            end
            t1=msgbox('Files saved successfully!');
            pause(2)
            delete(t1);
            
        end

        % Menu selected function: EstimateValuesMenu
        function EstimateValuesMenuSelected(app, event)
            nameT=erase(app.Tx1,'.');
            nameT=erase(nameT,':');
            saveR=['Save_' nameT];
            mkdir(saveR);
            cd(saveR);
            %%%%%%%%%%%%%%%%AUMENTAR CY OPCION ALL SLICE
            val=estimateV(app);
            [~,~,f]=size(app.imR);
            r1=round((60/app.HR)*1000);
            r2=(app.ED-1)*(r1/(f-1));
            r3=(app.ES-1)*(r1/(f-1));
            r=[r1,r2,r3,app.HR];
            EDV=str2double(val(1));
            EDRV=str2double(val(2));
            ESV=str2double(val(3));
        
            h=figure("Visible","off");
            plot(app.x,app.y); 
            hold on
            plot(app.x,app.y1);
            hold on
            plot(app.x,app.y2);
            hold off
            xlim([0-r(1)*0.1,(r(1)+(r(1)*0.1))])
            if EDV>EDRV
                lim=EDV;
            else
                lim=EDRV;
            end
            ylim([0,(lim+(lim*0.1))])
            xt = [r(2) r(3)];
            yt = [EDV ESV];
            str = {'ED','ES'};
            text(xt,yt,str);
            lgd = legend('Left','Right','LVM','Location','southeast');
            title(lgd,'Ventricle')
            grid on
            ylabel('Volume (ml)')
            xlabel('Time (ms)')
            %print(h,'GraphFcardiac','-dpdf','-bestfit');
            
            name=['figure_' nameT '.jpg'];
            saveas(h,name);
            close(h)
            
            %%%%% CÁLCULO PARÁMETROS Funcion Cardiaca
            EDV=str2double(val(1));
            ESV=str2double(val(3));
            EDRV=str2double(val(2));
            ESRV=str2double(val(4));
            StV=EDV-ESV;
            RVStv=EDRV-ESRV;
            EF=round(((StV/EDV)*100),2);
            EFRV=round(((RVStv/EDRV)*100),2);
            CO=round(((StV*r(4))/1000),2);
            
            T = table(val(1),val(3),StV,EF,CO,app.HR,val(2),val(4),RVStv,EFRV, ...
                'VariableNames', {'EDV LV','ESV LV','SV LV','EF LV','CO LV' ...
                ,'HR','EDV RV','ESV RV','SV RV','EF RV'} );
            name1=['info_' nameT '.txt'];
            writetable(T, name1)
            cd ..
            t=msgbox('Document and Image created successfully!');
            pause(2)
            delete(t);
        end

        % Menu selected function: matFileMenu
        function matFileMenuSelected(app, event)
            CleanS(app);            
            app.ProcessButtonGroup.Visible = 'on';
            app.VENTSEGLabel.Visible = 'on';
            app.LoadDicomFilesButton.Text='Load Files';
            app.LoadFoldersButton.Value=true;
            app.SegmentingMiddleSliceButton.Visible= 'off';
            app.SelectingEDandESButton.Visible= 'off';
            app.SegmentingEDButton.Visible= 'off';
            app.SegmentingESButton.Visible= 'off';
            app.LoadFoldersButton.FontWeight = 'bold';
            app.LoadDicomFilesButton.FontWeight = 'normal';
            app.SelectingEDandESButton.FontWeight = 'normal';
           
            %INFORMATION
            t=msgbox('Select the folder that contains the two files: orginal.mat and info_dicom.mat ');
            pause(3)
            delete(t);
            app.folder_name = uigetdir([],'Load Folder...');
            %Validation Folder
            if app.folder_name==0
                t=msgbox('Folder not selected ');
                pause(2)
                delete(t);
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;
            end
            %Validation mat files: length
            filePattern_mat = sprintf('%s/*.mat', app.folder_name);
            baseFileNames_mat = dir(filePattern_mat);
            numberOfFiles_mat = length(baseFileNames_mat);
            if numberOfFiles_mat<=1
                t=msgbox('Insufficient files');
                pause(2)
                delete(t);
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%FIND ORIGINAL.mat 
            try
                file='\original.mat';
                app.original1=load([app.folder_name,file]);
                app.original1=cell2mat(struct2cell(app.original1));
            catch
                t=msgbox('File not found: original.mat');
                pause(2)
                delete(t);
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;             
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%FIND dicom_info.mat
            try
                file1='\info_dicom.mat';
                app.info_dicom=load([app.folder_name,file1]);
                app.info_dicom=cell2mat(struct2cell(app.info_dicom));
                app.info_dicom1=app.info_dicom;
            catch
                t=msgbox('File not found: info_dicom.mat');
                pause(2)
                delete(t);
                return;             
            end
            %Create matrix Original
            original=app.original1;
            save original.mat original
            %Show in the app
            app.LoadFoldersButton.Value=false;
            app.LoadDicomFilesButton.Value=true;
            app.LoadDicomFilesButton.FontWeight = 'bold';
            app.LoadFoldersButton.FontWeight = 'normal';
            
            pause(0.5);
            %Execute function to segmentation
            SegmentFunc(app);
            textInf(app);
            idicom(app);
            
        end

        % Menu selected function: PreviousFileMenu
        function PreviousFileMenuSelected(app, event)
            CleanS(app);            
            app.ProcessButtonGroup.Visible = 'on';
            app.VENTSEGLabel.Visible = 'on';
            app.LoadDicomFilesButton.Text='Load Files';
            app.LoadFoldersButton.Value=true;
            app.SegmentingMiddleSliceButton.Visible= 'off';
            app.SelectingEDandESButton.Visible= 'off';
            app.SegmentingEDButton.Visible= 'off';
            app.SegmentingESButton.Visible= 'off';
            app.LoadFoldersButton.FontWeight = 'bold';
            app.LoadDicomFilesButton.FontWeight = 'normal';
            app.SelectingEDandESButton.FontWeight = 'normal';
            
            %INFORMATION
            t=msgbox('Select the folder that contains the three files: orginal.mat, segment.mat and info_dicom.mat ');
            pause(3)
            delete(t);
            app.folder_name = uigetdir([],'Load Folder...');
            %Validation Folder
            if app.folder_name==0
                t=msgbox('Folder not selected ');
                pause(2)
                delete(t);
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;
            end
            %Validation mat files: length
            filePattern_mat = sprintf('%s/*.mat', app.folder_name);
            baseFileNames_mat = dir(filePattern_mat);
            numberOfFiles_mat = length(baseFileNames_mat);
            if numberOfFiles_mat<=2
                t=msgbox('Insufficient files');
                pause(2)
                delete(t);
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%FIND ORIGINAL.mat 
            try
                file='\original.mat';
                app.original1=load([app.folder_name,file]);
                app.original1=cell2mat(struct2cell(app.original1));
            catch
                t=msgbox('File not found: original.mat');
                pause(2)
                delete(t);
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;             
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%FIND dicom_info.mat
            try
                file1='\info_dicom.mat';
                app.info_dicom=load([app.folder_name,file1]);
                app.info_dicom=cell2mat(struct2cell(app.info_dicom));
                app.info_dicom1=app.info_dicom;
            catch
                t=msgbox('File not found: info_dicom.mat');
                pause(2)
                delete(t);
                return;             
            end
            try
                file2='\segment.mat';
                app.totPh=load([app.folder_name,file2]);
                app.totPh=cell2mat(struct2cell(app.totPh));
            catch
                t=msgbox('File not found: segment.mat');
                pause(2)
                delete(t);
                app.ProcessButtonGroup.Visible = 'off';
                app.VENTSEGLabel.Visible = 'off';
                return;             
            end
            pause(0.5);
            %Show in the app
            app.LoadFoldersButton.Value=false;
            app.LoadDicomFilesButton.Value=true;
            app.LoadDicomFilesButton.FontWeight = 'bold';
            app.LoadFoldersButton.FontWeight = 'normal';
            
            pause(0.5);
            %Execute function to segmentation
            textInf(app);
            idicom(app);
            
            [~, ~, c, d]=size(app.totPh);
            
            P1=sum(sum(sum(app.totPh(:,:,:,1))));
            P2=sum(sum(sum(app.totPh(:,:,:,13))));
            P3=sum(sum(sum(app.totPh(:,:,:,round(d/2)))));
            
            %CALCULO AREA
            med=round(c/2)+1;
            resultP=app.totPh(:,:,med,:);
            app.imR=app.totPh(:,:,med,:);
            [~,~,c]=size(resultP);
            mask=resultP;
            auxT=(mask==1);
            val = zeros(1,c);
            cont=0;
            for k=1:c
                stats=sum(sum(sum(auxT(:,:,k))));
                val(k)=stats;
                if stats==0
                    cont=cont+1;
                end
            end
            app.ED=find(val==max(val));
            app.ED=app.ED(1);
            val1 = zeros(1,c-cont);
            cont=1;
            for k=1:c
                if val(k)~=0
                    val1(cont)=val(k);
                    cont=cont+1;
                end
            end
            
            app.ES=find(val==min(val1));
            app.ES=app.ES(1);
            app.EDdata=app.totPh(:,:,:,app.ED);
            app.ESdata=app.totPh(:,:,:,app.ES);
            
            %SELECCION ALL SLICES OR ED&ES
            if P1==0 || P2==0 || P3==0
                app.Cy=1;
                
                app.phase=app.ED;
                %APP Box Inicial
                app.CardiacCyclePhaseButtonGroup.Visible = 'on';
                showinform(app,app.EDdata);
                app.EDaut=app.EDdata;
                app.ESaut=app.ESdata;
                
            else
                app.Cy=2;
                app.phase=1;
                app.PHASESpinnerLabel.Visible = 'on';
                app.PHASESpinner.Visible = 'on';
                app.AutoCorrectButton.Visible='off';
                app.PHASESpinner.Limits = [1 d];
                showinform(app,app.totPh(:,:,:,1));
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.OPENMenu = uimenu(app.UIFigure);
            app.OPENMenu.Text = 'OPEN';
            app.DicomFileMenu = uimenu(app.OPENMenu);
            app.DicomFileMenu.MenuSelectedFcn = createCallbackFcn(app, @DicomFileMenuSelected, true);
            app.DicomFileMenu.Tooltip = {'Dicom folder'; ''};
            app.DicomFileMenu.Text = 'Dicom File';
            app.matFileMenu = uimenu(app.OPENMenu);
            app.matFileMenu.MenuSelectedFcn = createCallbackFcn(app, @matFileMenuSelected, true);
            app.matFileMenu.Text = '.mat File';
            app.PreviousFileMenu = uimenu(app.OPENMenu);
            app.PreviousFileMenu.MenuSelectedFcn = createCallbackFcn(app, @PreviousFileMenuSelected, true);
            app.PreviousFileMenu.Text = 'Previous File';
            app.SAVEMenu = uimenu(app.UIFigure);
            app.SAVEMenu.Text = 'SAVE';
            app.SegmentationMenu = uimenu(app.SAVEMenu);
            app.SegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentationMenuSelected, true);
            app.SegmentationMenu.Tooltip = {'Save .dicom'};
            app.SegmentationMenu.Enable = 'off';
            app.SegmentationMenu.Text = 'Segmentation';
            app.EstimateValuesMenu = uimenu(app.SAVEMenu);
            app.EstimateValuesMenu.MenuSelectedFcn = createCallbackFcn(app, @EstimateValuesMenuSelected, true);
            app.EstimateValuesMenu.Tooltip = {'Save PDF'};
            app.EstimateValuesMenu.Enable = 'off';
            app.EstimateValuesMenu.Text = 'Estimate Values';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SLICESliderLabel = uilabel(app.GridLayout);
            app.SLICESliderLabel.Tag = 'slice';
            app.SLICESliderLabel.Visible = 'off';
            app.SLICESliderLabel.Text = 'SLICE';
            app.SLICESlider = uislider(app.GridLayout);
            app.SLICESlider.Limits = [1 100];
            app.SLICESlider.MajorTicks = [];
            app.SLICESlider.ValueChangedFcn = createCallbackFcn(app, @SLICESliderValueChanged, true);
            app.SLICESlider.ValueChangingFcn = createCallbackFcn(app, @SLICESliderValueChanging, true);
            app.SLICESlider.MinorTicks = [];
            app.SLICESlider.Tag = 'slider';
            app.SLICESlider.Visible = 'off';
            app.SLICESlider.Value = 1;
            app.VENTSEGLabel = uilabel(app.GridLayout);
            app.VENTSEGLabel.Visible = 'off';
            app.VENTSEGLabel.Text = 'VENTSEG';
            app.DropDownLabel = uilabel(app.GridLayout);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = '';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'All', 'Left Ventricle', 'Rigth Ventricle', 'Myo'};
            app.DropDown.ItemsData = [1 2 3 4];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 1;
            app.INFORMATIONEditFieldLabel = uilabel(app.GridLayout);
            app.INFORMATIONEditFieldLabel.Visible = 'off';
            app.INFORMATIONEditFieldLabel.Text = 'INFORMATION';
            app.INFORMATIONEditField = uieditfield(app.GridLayout, 'text');
            app.INFORMATIONEditField.Editable = 'off';
            app.INFORMATIONEditField.Visible = 'off';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.Label = uilabel(app.GridLayout);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.ESTIMATEButton = uibutton(app.GridLayout, 'push');
            app.ESTIMATEButton.ButtonPushedFcn = createCallbackFcn(app, @ESTIMATEButtonPushed, true);
            app.ESTIMATEButton.Visible = 'off';
            app.ESTIMATEButton.Text = 'ESTIMATE';
            app.CORRECTButton = uibutton(app.GridLayout, 'push');
            app.CORRECTButton.ButtonPushedFcn = createCallbackFcn(app, @CORRECTButtonPushed, true);
            app.CORRECTButton.Enable = 'off';
            app.CORRECTButton.Visible = 'off';
            app.CORRECTButton.Text = 'CORRECT';
            app.CardiacCyclePhaseButtonGroup = uibuttongroup(app.GridLayout);
            app.CardiacCyclePhaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CardiacCyclePhaseButtonGroupSelectionChanged, true);
            app.CardiacCyclePhaseButtonGroup.Title = 'Cardiac Cycle Phase';
            app.CardiacCyclePhaseButtonGroup.Visible = 'off';
            app.EndDiastoleButton = uiradiobutton(app.CardiacCyclePhaseButtonGroup);
            app.EndDiastoleButton.Text = 'End Diastole';
            app.EndDiastoleButton.Value = true;
            app.EndSistoleButton = uiradiobutton(app.CardiacCyclePhaseButtonGroup);
            app.EndSistoleButton.Text = 'End Sistole';
            app.FreeHandButtonGroup = uibuttongroup(app.GridLayout);
            app.FreeHandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FreeHandButtonGroupSelectionChanged, true);
            app.FreeHandButtonGroup.Title = 'Free Hand';
            app.FreeHandButtonGroup.Visible = 'off';
            app.AddButton = uiradiobutton(app.FreeHandButtonGroup);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uiradiobutton(app.FreeHandButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.dButton = uiradiobutton(app.FreeHandButtonGroup);
            app.dButton.Enable = 'off';
            app.dButton.Visible = 'off';
            app.dButton.Text = 'd';
            app.dButton.Value = true;
            app.SAVEButton = uibutton(app.GridLayout, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Visible = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.PhaseViewButton = uibutton(app.GridLayout, 'push');
            app.PhaseViewButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseViewButtonPushed, true);
            app.PhaseViewButton.Visible = 'off';
            app.PhaseViewButton.Text = 'Phase View';
            app.ProcessButtonGroup = uibuttongroup(app.GridLayout);
            app.ProcessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessButtonGroupSelectionChanged, true);
            app.ProcessButtonGroup.Enable = 'off';
            app.ProcessButtonGroup.Title = 'Process';
            app.ProcessButtonGroup.Visible = 'off';
            app.LoadFoldersButton = uiradiobutton(app.ProcessButtonGroup);
            app.LoadFoldersButton.Text = 'Load Folders';
            app.LoadFoldersButton.Value = true;
            app.LoadDicomFilesButton = uiradiobutton(app.ProcessButtonGroup);
            app.LoadDicomFilesButton.Text = 'Load Dicom Files';
            app.SegmentingMiddleSliceButton = uiradiobutton(app.ProcessButtonGroup);
            app.SegmentingMiddleSliceButton.Text = 'Segmenting Middle Slice';
            app.SelectingEDandESButton = uiradiobutton(app.ProcessButtonGroup);
            app.SelectingEDandESButton.Text = 'Selecting ED and ES';
            app.SegmentingEDButton = uiradiobutton(app.ProcessButtonGroup);
            app.SegmentingEDButton.Text = 'Segmenting ED';
            app.SegmentingESButton = uiradiobutton(app.ProcessButtonGroup);
            app.SegmentingESButton.Text = 'Segmenting ES';
            app.PHASESpinnerLabel = uilabel(app.GridLayout);
            app.PHASESpinnerLabel.Visible = 'off';
            app.PHASESpinnerLabel.Text = 'PHASE';
            app.PHASESpinner = uispinner(app.GridLayout);
            app.PHASESpinner.Limits = [1 10];
            app.PHASESpinner.ValueChangedFcn = createCallbackFcn(app, @PHASESpinnerValueChanged, true);
            app.PHASESpinner.Visible = 'off';
            app.PHASESpinner.Value = 1;
            app.SegmentationButtonGroup = uibuttongroup(app.GridLayout);
            app.SegmentationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationButtonGroupSelectionChanged, true);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.SegmentationButtonGroup.Visible = 'off';
            app.ActualButton = uiradiobutton(app.SegmentationButtonGroup);
            app.ActualButton.Text = 'Actual';
            app.ActualButton.Value = true;
            app.AutoCorrectButton = uiradiobutton(app.SegmentationButtonGroup);
            app.AutoCorrectButton.Text = 'Auto Correct';
            app.NoneButton = uiradiobutton(app.SegmentationButtonGroup);
            app.NoneButton.Text = 'None';
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segmentacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end